classdef String_vibrations < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        RightPanel               matlab.ui.container.Panel
        axes                     matlab.ui.control.UIAxes
        LeftPanel                matlab.ui.container.Panel
        eqation                  matlab.ui.control.Label
        label_speed              matlab.ui.control.Label
        speed_val                matlab.ui.control.NumericEditField
        label_left_border        matlab.ui.control.Label
        left_border_val          matlab.ui.control.NumericEditField
        label_right_border       matlab.ui.control.Label
        right_border_val         matlab.ui.control.NumericEditField
        label_time_begin         matlab.ui.control.Label
        time_begin_val           matlab.ui.control.NumericEditField
        label_time_end           matlab.ui.control.Label
        time_end_val             matlab.ui.control.NumericEditField
        label_discretization     matlab.ui.control.Label
        discretization_val       matlab.ui.control.NumericEditField
        label_initial_position   matlab.ui.control.Label
        initial_position_val     matlab.ui.control.EditField
        label_initial_speed      matlab.ui.control.Label
        initial_speed_val        matlab.ui.control.EditField
        label_force              matlab.ui.control.Label
        force                    matlab.ui.control.EditField
        label_left_boundary      matlab.ui.control.Label
        left_boundary_function   matlab.ui.control.EditField
        left_boundary_type       matlab.ui.container.ButtonGroup
        left_boundary_position   matlab.ui.control.RadioButton
        left_boundary_speed      matlab.ui.control.RadioButton
        right_boundary_type      matlab.ui.container.ButtonGroup
        right_boundary_position  matlab.ui.control.RadioButton
        right_boundary_speed     matlab.ui.control.RadioButton
        label_right_boundary     matlab.ui.control.Label
        right_boundary_function  matlab.ui.control.EditField
        button_calculate         matlab.ui.control.Button
        button_plot              matlab.ui.control.Button
        button_animate           matlab.ui.control.Button
    end

    
    properties (Access = private)
        stringPositions
        X
        T
    end
    
    methods (Access = private)
        
        function [X, T, s] = calculateString(app)
            d = uiprogressdlg(app.UIFigure, "Title", "Ожидайте" , ...
                "Message", "Идёт расчёт...");
            
            % параметры
            x_lim = [app.left_border_val.Value app.right_border_val.Value];  % границы пространства
            t_lim = [app.time_begin_val.Value app.time_end_val.Value]; % границы по времени
            c = app.speed_val.Value;          % скорость звука
            dx = app.discretization_val.Value;     % пространственный шаг
            
            % внешняя сила
            external_force = str2func("@(x, t) " + app.force.Value);
            % начальное значение
            init_value = str2func("@(x) " + app.initial_position_val.Value);
            % начальная скорость
            init_speed = str2func("@(x) " + app.initial_speed_val.Value);
            
            % граничные условия
            left_constrain = str2func("@(t) " + app.left_boundary_function.Value);
            right_constrain = str2func("@(t) " + app.right_boundary_function.Value);
            
            % расчет переменных
            dt = dx / c;                % шаг по времени
            x = x_lim(1):dx:x_lim(2);   % вектор значений коордитнаты
            t = t_lim(1):dt:t_lim(2);   % вектор значений времени
            
            % создание массива для решения
            string = zeros(length(t), length(x));
            
            % начальные условия
            string(1, :) = init_value(x);
            string(2, :) = string(1, :) + dt * init_speed(x);
            
            % граничные условия
            if (app.left_boundary_position.Value)
                string(:, 1) = left_constrain(t);
            elseif (app.left_boundary_speed.Value)
                for i = 2:length(t)
                    string(i, 1) = string(i - 1, 1) + ...
                        dt * left_constrain(t(i));
                end
            end

            if (app.right_boundary_position.Value)
                string(:, end) = right_constrain(t);
            elseif (app.right_boundary_speed.Value)
                for i = 2:length(t)
                    string(i, end) = string(i - 1, end) + ...
                        dt * right_constrain(t(i));
                end
            end
                        
            % расчёт в цикле
            for i = 3:size(string, 1)
                for j = 2:(size(string, 2) - 1)
                    string(i, j) = string(i - 1, j - 1) + string(i - 1, j + 1) - ...
                        string(i - 2, j) + dt^2 * external_force(x(j), t(i));
                end
                d.Value = i / size(string, 1);
            end

            s = string;
            [X, T] = meshgrid(x, t);
            
            close(d)
        end
        
        function plotEverything(app)
            contour(app.axes, app.X, app.T, app.stringPositions, ...
                "Fill", "on", "LineColor", "k", "LineWidth", 1);
            xlabel(app.axes, "x");
            ylabel(app.axes, "t");
            xlim(app.axes, [app.left_border_val.Value app.right_border_val.Value]);
            ylim(app.axes, [app.time_begin_val.Value app.time_end_val.Value]);
            title(app.axes, "");
            c = colorbar(app.axes);
            c.Label.String = "y";
        end
        
        function update_eqation(app)

            text = "$$ \left\{\begin{array}{@{}l@{}} " + ...
                "\displaystyle \frac{\partial^2u}{\partial t^2} = \frac{\partial^2u}{\partial x^2} + f(x, t) - волновое\;уравнение \\" + ...
                "u|_{t=0} = \varphi (x) - начальное\;положение \\" + ...
                "u'_{t}|_{t=0} = \psi (x) - начальная\;скорость \\";

            if(app.left_boundary_position.Value)
                text = text + "u|_{x=0} = \lambda (t) - граничное\;условие\;слева \\";
            else
                text = text + "u'_t|_{x=0} = \lambda (t) - граничное\;условие\;слева \\";
            end

            if(app.right_boundary_position.Value)
                text = text + "u|_{x=l} = \mu (t) - граничное\;условие\;справа";
            else
                text = text + "u'_t|_{x=l} = \mu (t) - граничное\;условие\;справа";
            end

            text = text + "\end{array}\right.\,$$";

            app.eqation.Text = text;
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            update_eqation(app)
        end

        % Button pushed function: button_calculate
        function button_calculateButtonPushed(app, event)
            [app.X, app.T, app.stringPositions] = calculateString(app);
            
            plotEverything(app)
        end

        % Button pushed function: button_animate
        function button_animatePushed(app, event)

            if (isempty(app.stringPositions))
                [app.X, app.T, app.stringPositions] = calculateString(app);
            end

            p = plot(app.axes, app.X(1, :), app.stringPositions(1, :), ...
                "LineWidth", 2);
            s = subtitle(app.axes, "t = " + app.T(1, 1));
            xlabel(app.axes, "x");
            ylabel(app.axes, "y");
            xlim(app.axes, [app.left_border_val.Value app.right_border_val.Value]);
            ylim(app.axes, [min(app.stringPositions, [], "all") ...
                max(app.stringPositions, [], "all")]);
            
            
            for i = 1:size(app.stringPositions, 1)
                p.YData = app.stringPositions(i, :);
                s.String = "t = " + app.T(i, 1);
                drawnow
            end
        end

        % Button pushed function: button_plot
        function button_plotButtonPushed(app, event)
            if (isempty(app.stringPositions))
                [app.X, app.T, app.stringPositions] = calculateString(app);
            end

            plotEverything(app)
        end

        % Selection changed function: left_boundary_type, 
        % ...and 1 other component
        function boundary_type_changed(app, event)
            update_eqation(app)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'Моделирование колебаний струны';
            app.LeftPanel = uipanel(app.UIFigure);
            app.button_animate = uibutton(app.LeftPanel, 'push');
            app.button_animate.ButtonPushedFcn = createCallbackFcn(app, @button_animatePushed, true);
            app.button_animate.Text = 'Анимация';
            app.button_plot = uibutton(app.LeftPanel, 'push');
            app.button_plot.ButtonPushedFcn = createCallbackFcn(app, @button_plotButtonPushed, true);
            app.button_plot.Text = 'Общий график';
            app.button_calculate = uibutton(app.LeftPanel, 'push');
            app.button_calculate.ButtonPushedFcn = createCallbackFcn(app, @button_calculateButtonPushed, true);
            app.button_calculate.Text = 'Расчитать';
            app.right_boundary_function = uieditfield(app.LeftPanel, 'text');
            app.right_boundary_function.Value = '0';
            app.label_right_boundary = uilabel(app.LeftPanel);
            app.label_right_boundary.Text = 'Граничное условие справа';
            app.right_boundary_type = uibuttongroup(app.LeftPanel);
            app.right_boundary_type.SelectionChangedFcn = createCallbackFcn(app, @boundary_type_changed, true);
            app.right_boundary_type.Title = 'Тип граничного условия справа';
            app.right_boundary_speed = uiradiobutton(app.right_boundary_type);
            app.right_boundary_speed.Text = 'Скорость';
            app.right_boundary_position = uiradiobutton(app.right_boundary_type);
            app.right_boundary_position.Text = 'Положение';
            app.right_boundary_position.Value = true;
            app.left_boundary_type = uibuttongroup(app.LeftPanel);
            app.left_boundary_type.SelectionChangedFcn = createCallbackFcn(app, @boundary_type_changed, true);
            app.left_boundary_type.Title = 'Тип граничного условия слева';
            app.left_boundary_speed = uiradiobutton(app.left_boundary_type);
            app.left_boundary_speed.Text = 'Скорость';
            app.left_boundary_position = uiradiobutton(app.left_boundary_type);
            app.left_boundary_position.Text = 'Положение';
            app.left_boundary_position.Value = true;
            app.left_boundary_function = uieditfield(app.LeftPanel, 'text');
            app.left_boundary_function.Value = '0';
            app.label_left_boundary = uilabel(app.LeftPanel);
            app.label_left_boundary.Text = 'Граничное условие слева';
            app.force = uieditfield(app.LeftPanel, 'text');
            app.force.Value = '0';
            app.label_force = uilabel(app.LeftPanel);
            app.label_force.Text = 'Сила f(x, t)';
            app.initial_speed_val = uieditfield(app.LeftPanel, 'text');
            app.initial_speed_val.Value = '0';
            app.label_initial_speed = uilabel(app.LeftPanel);
            app.label_initial_speed.Text = 'Начальная скорость u''(x)';
            app.initial_position_val = uieditfield(app.LeftPanel, 'text');
            app.initial_position_val.Value = 'sin(pi*x)';
            app.label_initial_position = uilabel(app.LeftPanel);
            app.label_initial_position.Text = 'Начальное положение u(x)';
            app.discretization_val = uieditfield(app.LeftPanel, 'numeric');
            app.discretization_val.Value = 0.001;
            app.label_discretization = uilabel(app.LeftPanel);
            app.label_discretization.Text = 'Шаг дискретизации по длине';
            app.time_end_val = uieditfield(app.LeftPanel, 'numeric');
            app.time_end_val.Value = 1;
            app.label_time_end = uilabel(app.LeftPanel);
            app.label_time_end.Text = 'Конечный момент времени';
            app.time_begin_val = uieditfield(app.LeftPanel, 'numeric');
            app.label_time_begin = uilabel(app.LeftPanel);
            app.label_time_begin.Text = 'Начальный момент времени';
            app.right_border_val = uieditfield(app.LeftPanel, 'numeric');
            app.right_border_val.Value = 1;
            app.label_right_border = uilabel(app.LeftPanel);
            app.label_right_border.Text = 'Правая граница';
            app.left_border_val = uieditfield(app.LeftPanel, 'numeric');
            app.label_left_border = uilabel(app.LeftPanel);
            app.label_left_border.Text = 'Левая граница';
            app.speed_val = uieditfield(app.LeftPanel, 'numeric');
            app.speed_val.Limits = [0 Inf];
            app.speed_val.Value = 1;
            app.label_speed = uilabel(app.LeftPanel);
            app.label_speed.Text = 'Скорость звука';
            app.eqation = uilabel(app.LeftPanel);
            app.eqation.Interpreter = 'latex';
            app.eqation.Text = '';
            app.RightPanel = uipanel(app.UIFigure);
            app.axes = uiaxes(app.RightPanel);
            xlabel(app.axes, 'x, м')
            ylabel(app.axes, 't, c')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = String_vibrations

            runningApp = getRunningApp(app);

            % Check for running singleton app
            if isempty(runningApp)

                % Create UIFigure and components
                createComponents(app)

                % Register the app with App Designer
                registerApp(app, app.UIFigure)

                % Execute the startup function
                runStartupFcn(app, @startupFcn)
            else

                % Focus the running singleton app
                figure(runningApp.UIFigure)

                app = runningApp;
            end

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end